classdef RootFinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RootFinderLabel               matlab.ui.control.Label
        InputPanel                    matlab.ui.container.Panel
        InsertequationEditFieldLabel  matlab.ui.control.Label
        InsertequationEditField       matlab.ui.control.EditField
        ChoosemethodDropDownLabel     matlab.ui.control.Label
        ChoosemethodDropDown          matlab.ui.control.DropDown
        ofIterationsEditFieldLabel    matlab.ui.control.Label
        ofIterationsEditField         matlab.ui.control.NumericEditField
        PrecisionEditFieldLabel       matlab.ui.control.Label
        PrecisionEditField            matlab.ui.control.NumericEditField
        InitialPointEditFieldLabel    matlab.ui.control.Label
        InitialPointEditField         matlab.ui.control.NumericEditField
        SolveButton                   matlab.ui.control.Button
        SecondPointEditFieldLabel     matlab.ui.control.Label
        SecondPointEditField          matlab.ui.control.NumericEditField
        forclosedmethodsLabel         matlab.ui.control.Label
        forfixediterationLabel        matlab.ui.control.Label
        MagicFormulaEditFieldLabel    matlab.ui.control.Label
        MagicFormulaEditField         matlab.ui.control.EditField
        OutputPanel                   matlab.ui.container.Panel
        UIAxes2                       matlab.ui.control.UIAxes
        UITable                       matlab.ui.control.Table
    end

    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            method = app.ChoosemethodDropDown.Value;
            fx = app.InsertequationEditField.Value;
            n = app.ofIterationsEditField.Value;
            p = app.PrecisionEditField.Value;
            x1 = app.InitialPointEditField.Value;
            method=method-48;
            switch(method)
                case 1 
                  fprintf('Excellent!\n' );
                case 2 
                  fprintf('Well done\n' );
                case 4 %fixed point
                  gx = app.MagicFormulaEditField.Value;
                  [i,r,er,px,s] = FixedPoint_iteration(fx,gx,n,p,x1);
                  I=transpose(i);
                  R=transpose(r);
                  E=transpose(er);
                  P=transpose(px);
                  t = table(I(1:s),R(1:s),E(1:s),P(1:s));
                  app.UITable.Data=t;
                  n1=x1+10;
                  n2=n1-10;
                  n = [n2:1:n1];
                  f=str2func(['@(x)',fx]);
                  y=feval(f,n);
                  g=str2func(['@(x)',gx]);
                  z=feval(g,n);
                  w=n;
                  plot (app.UIAxes2,z,'b-');
                  hold(app.UIAxes2, 'on')
                  plot(app.UIAxes2,y,'r-');
                  hold(app.UIAxes2, 'on')
                  plot(app.UIAxes2,w,'g-');
                case 3 %regula falsi
                  fprintf('You passed\n' );
                case 5 % newton
                   [it,r,er,gx,dx,size] = NewtonRaphson_iteration(fx,x1,n,p);
                   I=transpose(it);
                   R=transpose(r);
                   E=transpose(er);
                   P=transpose(gx);
                   D=transpose(dx);
                   t = table(I(1:size),R(1:size),E(1:size),P(1:size));
                   app.UITable.Data=t;
                   f=str2func(['@(x)',fx]);
                   n1=x1+10;
                   n2=n1-10;
                   n = [n2:1:n1];
                   y=feval(f,n);
                   d = str2sym(fx);
                   d0=matlabFunction(diff(d));
                   d= feval(d0,n);
                   plot(app.UIAxes2,y,'r-');
                   hold(app.UIAxes2, 'on')
                   plot(app.UIAxes2,d,'b-');
                   hold(app.UIAxes2, 'on')
                case 6 %secant     
                    x2=app.SecondPointEditField.Value;
                  [it, r, er, fv,size] = Secant(fx,x1,n,p,x2);
                   I=transpose(it);
                   R=transpose(r);
                   E=transpose(er);
                   P=transpose(fv);
                   t = table(I(1:size),R(1:size),E(1:size),P(1:size));
                   app.UITable.Data=t;
                   f=str2func(['@(x)',fx]);
                   n1=x1+10;
                   n2=n1-10;
                   n = [n2:1:n1];
                   y=feval(f,n);
                   plot(app.UIAxes2,y,'r-');
                otherwise
                  fprintf("error");
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RootFinderLabel = uilabel(app.UIFigure);
            app.RootFinderLabel.Text = 'Root Finder';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.InsertequationEditFieldLabel = uilabel(app.InputPanel);
            app.InsertequationEditFieldLabel.Text = 'Insert equation';
            app.InsertequationEditField = uieditfield(app.InputPanel, 'text');
            app.ChoosemethodDropDownLabel = uilabel(app.InputPanel);
            app.ChoosemethodDropDownLabel.Text = 'Choose method';
            app.ChoosemethodDropDown = uidropdown(app.InputPanel);
            app.ChoosemethodDropDown.Items = {'Default', 'Bisection', 'Regula-falsi', 'Fixed Point Iteration', 'Newton Iteration', 'Secant'};
            app.ChoosemethodDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.ChoosemethodDropDown.Value = '1';
            app.ofIterationsEditFieldLabel = uilabel(app.InputPanel);
            app.ofIterationsEditFieldLabel.Text = '# of Iterations';
            app.ofIterationsEditField = uieditfield(app.InputPanel, 'numeric');
            app.ofIterationsEditField.Value = 50;
            app.PrecisionEditFieldLabel = uilabel(app.InputPanel);
            app.PrecisionEditFieldLabel.Text = 'Precision';
            app.PrecisionEditField = uieditfield(app.InputPanel, 'numeric');
            app.PrecisionEditField.Value = 1e-05;
            app.InitialPointEditFieldLabel = uilabel(app.InputPanel);
            app.InitialPointEditFieldLabel.Text = 'Initial Point';
            app.InitialPointEditField = uieditfield(app.InputPanel, 'numeric');
            app.SolveButton = uibutton(app.InputPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.SecondPointEditFieldLabel = uilabel(app.InputPanel);
            app.SecondPointEditFieldLabel.Text = 'Second Point';
            app.SecondPointEditField = uieditfield(app.InputPanel, 'numeric');
            app.forclosedmethodsLabel = uilabel(app.InputPanel);
            app.forclosedmethodsLabel.Text = 'for closed methods';
            app.forfixediterationLabel = uilabel(app.InputPanel);
            app.forfixediterationLabel.Text = 'for fixed iteration';
            app.MagicFormulaEditFieldLabel = uilabel(app.InputPanel);
            app.MagicFormulaEditFieldLabel.Text = 'MagicFormula';
            app.MagicFormulaEditField = uieditfield(app.InputPanel, 'text');
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.UIAxes2 = uiaxes(app.OutputPanel);
            title(app.UIAxes2, 'Graphical repreentation')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UITable = uitable(app.OutputPanel);
            app.UITable.ColumnName = {'iteration'; 'root'; 'approximate error'; 'f(root)'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = RootFinder

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end